classdef GUIParticleTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        SavesettingsButton             matlab.ui.control.Button
        TrackButton                    matlab.ui.control.Button
        ImageSharpeningSwitch          matlab.ui.control.Switch
        ImageSharpeningSwitchLabel     matlab.ui.control.Label
        ImagecontrastadjustmentSwitch  matlab.ui.control.Switch
        ImagecontrastadjustmentSwitchLabel  matlab.ui.control.Label
        BackgroundSubtractionSwitch    matlab.ui.control.Switch
        BackgroundSubtractionSwitchLabel  matlab.ui.control.Label
        LastImageEditField             matlab.ui.control.NumericEditField
        LastImageEditFieldLabel        matlab.ui.control.Label
        FirstImageEditField            matlab.ui.control.NumericEditField
        FirstImageEditFieldLabel       matlab.ui.control.Label
        BinaryThresholdSlider          matlab.ui.control.Slider
        BinaryThresholdSliderLabel     matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        GetImageDirectoryButton        matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img % Image to be used to check binary thresholding
        imgBitDepth %Bit depth of image given from directory
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BinaryThresholdSlider
        function BinaryThresholdSliderValueChanged(app, event)
            value = app.BinaryThresholdSlider.Value;
            app.TextArea.Value = num2str(value);
            

            imshow(app.img>value*2^app.imgBitDepth,'parent',app.UIAxes);
        end

        % Button pushed function: GetImageDirectoryButton
        function GetImageDirectoryButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            app.img = imread(fullfile(path,file));
            imgInfo = imfinfo(fullfile(path,file));
            app.imgBitDepth = imgInfo.BitDepth;

            imshow(app.img,'parent',app.UIAxes)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UIAxes = uiaxes(app.MainTab);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GetImageDirectoryButton = uibutton(app.MainTab, 'push');
            app.GetImageDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageDirectoryButtonPushed, true);
            app.GetImageDirectoryButton.Text = 'Get Image Directory';
            app.TextArea = uitextarea(app.MainTab);
            app.BinaryThresholdSliderLabel = uilabel(app.MainTab);
            app.BinaryThresholdSliderLabel.Text = 'Binary Threshold';
            app.BinaryThresholdSlider = uislider(app.MainTab);
            app.BinaryThresholdSlider.Limits = [0 1];
            app.BinaryThresholdSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.BinaryThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinaryThresholdSliderValueChanged, true);
            app.FirstImageEditFieldLabel = uilabel(app.MainTab);
            app.FirstImageEditFieldLabel.Text = 'First Image';
            app.FirstImageEditField = uieditfield(app.MainTab, 'numeric');
            app.LastImageEditFieldLabel = uilabel(app.MainTab);
            app.LastImageEditFieldLabel.Text = 'Last Image';
            app.LastImageEditField = uieditfield(app.MainTab, 'numeric');
            app.LastImageEditField.Value = 1000;
            app.BackgroundSubtractionSwitchLabel = uilabel(app.MainTab);
            app.BackgroundSubtractionSwitchLabel.Text = 'Background Subtraction';
            app.BackgroundSubtractionSwitch = uiswitch(app.MainTab, 'slider');
            app.ImagecontrastadjustmentSwitchLabel = uilabel(app.MainTab);
            app.ImagecontrastadjustmentSwitchLabel.Text = 'Image contrast adjustment';
            app.ImagecontrastadjustmentSwitch = uiswitch(app.MainTab, 'slider');
            app.ImageSharpeningSwitchLabel = uilabel(app.MainTab);
            app.ImageSharpeningSwitchLabel.Text = 'Image Sharpening';
            app.ImageSharpeningSwitch = uiswitch(app.MainTab, 'slider');
            app.TrackButton = uibutton(app.MainTab, 'push');
            app.TrackButton.Text = 'Track';
            app.SavesettingsButton = uibutton(app.MainTab, 'push');
            app.SavesettingsButton.Text = 'Save settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIParticleTracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end